classdef adohe_fototoko < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup2                    matlab.ui.container.TabGroup
        ImageTab                     matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        InputImage                   matlab.ui.control.Image
        OutputImage                  matlab.ui.control.Image
        EdgeImage                    matlab.ui.control.UIAxes
        HistogramTab_2               matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        OutputLabel                  matlab.ui.control.Label
        InputLabel                   matlab.ui.control.Label
        UIAxes6                      matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        TabGroup                     matlab.ui.container.TabGroup
        OpenFileTab                  matlab.ui.container.Tab
        GridLayout6_2                matlab.ui.container.GridLayout
        ChooseImageButton            matlab.ui.control.Button
        NoFileSelectedLabel          matlab.ui.control.Label
        TepiTab                      matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        RadiusOpenEditField          matlab.ui.control.NumericEditField
        RadiusOpenEditFieldLabel     matlab.ui.control.Label
        BentukDropDown               matlab.ui.control.DropDown
        BentukDropDownLabel          matlab.ui.control.Label
        csobelEditField              matlab.ui.control.NumericEditField
        csobelEditFieldLabel         matlab.ui.control.Label
        variansiLogEditField         matlab.ui.control.NumericEditField
        variansiLogEditFieldLabel    matlab.ui.control.Label
        filtersizeLogEditField       matlab.ui.control.NumericEditField
        filtersizeLogEditFieldLabel  matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        FillThresholdEditField       matlab.ui.control.NumericEditField
        FillThresholdEditFieldLabel  matlab.ui.control.Label
        RadiusCloseEditField         matlab.ui.control.NumericEditField
        RadiusCloseEditFieldLabel    matlab.ui.control.Label
        ProcessButton                matlab.ui.control.Button
        ButtonGroup                  matlab.ui.container.ButtonGroup
        CannyButton                  matlab.ui.control.RadioButton
        RobertsButton                matlab.ui.control.RadioButton
        PrewitButton                 matlab.ui.control.RadioButton
        SobelButton                  matlab.ui.control.RadioButton
        LoGButton                    matlab.ui.control.RadioButton
        LaplaceButton                matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        Fullimagename % Full image name
        Inputimagedata % Image data (input)
        Outputimagedata % Image data (output)
        SpecTargetImagedata % Description
        SpecTargetFullImagename % Description
    end
    
    methods (Access = private)
        
        function setHistogram(~, imagedata, UIImage, RAxes, GAxes, BAxes)
            % clear histogram
            cla(RAxes);
            cla(GAxes);
            cla(BAxes);
            title(RAxes, '');
            title(GAxes, '');
            title(BAxes, '');

            % handle rgb image
            numChannel = size(imagedata, 3);
            if numChannel == 3
                % Display the image in the ui image component
                UIImage.ImageSource = imagedata;
            
                % Separate the RGB channels
                redChannel = imagedata(:, :, 1);
                greenChannel = imagedata(:, :, 2);
                blueChannel = imagedata(:, :, 3);
                
                % create histogram matrix
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);             

                % Calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate r,g, and b value
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(RAxes, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(RAxes, 'on');
                bar(GAxes, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(GAxes, 'on');
                bar(BAxes, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(BAxes, 'on');
                
                % Set titles for each axes
                title(RAxes, 'Red Channel Histogram');
                title(GAxes, 'Green Channel Histogram');
                title(BAxes, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(RAxes, [0, max(redHistogram)]); 
                ylim(GAxes, [0, max(greenHistogram)]);
                ylim(BAxes, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(RAxes, [0, 256]);
                xlim(GAxes, [0, 256]);
                xlim(BAxes, [0, 256]);

                % Turn off hold for all axes
                hold(RAxes, 'off');
                hold(GAxes, 'off');
                hold(BAxes, 'off');
           
           % handle grayscale image
           elseif numChannel == 1
                % set to show image for greyscale
                rgbimage = cat(3, imagedata(:, :, 1),imagedata(:, :, 1),imagedata(:, :, 1));
                % Display the image in the uiimage component
                UIImage.ImageSource = rgbimage;
                
                % grey channel
                greyChannel = imagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate grey value
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % plot histograms
                bar(RAxes,0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(RAxes, 'on');
                
                % Set the limit to the maximum value
                ylim(RAxes, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(RAxes, [0, 256]);
                
                % Set a title for the axis
                title(RAxes, 'Grayscale Image Histogram');
                hold(RAxes, 'off');
           else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
           end
        end
        function ToggleLoGInput(app, isOpen)
            if(isOpen)
                app.variansiLogEditField.Visible = "on";
                app.variansiLogEditFieldLabel.Visible = "on";
                app.filtersizeLogEditField.Visible = "on";
                app.filtersizeLogEditFieldLabel.Visible = "on";
            else
                app.variansiLogEditField.Visible = "off";
                app.variansiLogEditFieldLabel.Visible = "off";
                app.filtersizeLogEditField.Visible = "off";
                app.filtersizeLogEditFieldLabel.Visible = "off";
            end
        end
        function ToggleSobelInput(app, isOpen)
           if(isOpen)
                app.csobelEditField.Visible = "on";
                app.csobelEditFieldLabel.Visible = "on";
            else
                app.csobelEditField.Visible = "off";
                app.csobelEditFieldLabel.Visible = "off";
            end
        end 
        function setHistogramInput(app, numChannel)
            
            % clear histogram
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            title(app.UIAxes, '');
            title(app.UIAxes2, '');
            title(app.UIAxes3, '');

            % handle rgb image
            if numChannel == 3
                % Display the image in the ui image component
                app.InputImage.ImageSource = app.Inputimagedata;
            
                % Separate the RGB channels
                redChannel = app.Inputimagedata(:, :, 1);
                greenChannel = app.Inputimagedata(:, :, 2);
                blueChannel = app.Inputimagedata(:, :, 3);
                
                % create histogram matrix
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);             

                % Calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate r,g, and b value
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(app.UIAxes, 'on');
                bar(app.UIAxes2, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(app.UIAxes2, 'on');
                bar(app.UIAxes3, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(app.UIAxes3, 'on');
                
                % Set titles for each axes
                title(app.UIAxes, 'Red Channel Histogram');
                title(app.UIAxes2, 'Green Channel Histogram');
                title(app.UIAxes3, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes, [0, max(redHistogram)]); 
                ylim(app.UIAxes2, [0, max(greenHistogram)]);
                ylim(app.UIAxes3, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes, [0, 256]);
                xlim(app.UIAxes2, [0, 256]);
                xlim(app.UIAxes3, [0, 256]);

                % Turn off hold for all axes
                hold(app.UIAxes, 'off');
                hold(app.UIAxes2, 'off');
                hold(app.UIAxes3, 'off');
           
           % handle grayscale image
           elseif numChannel == 1
                % set to show image for greyscale
                rgbimage = cat(3, app.Inputimagedata(:, :, 1),app.Inputimagedata(:, :, 1),app.Inputimagedata(:, :, 1));
                % Display the image in the uiimage component
                app.InputImage.ImageSource = rgbimage;
                
                % grey channel
                greyChannel = app.Inputimagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate grey value
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % plot histograms
                bar(app.UIAxes,0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(app.UIAxes, 'on');
                

                % Set the limit to the maximum value
                ylim(app.UIAxes, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes, [0, 256]);
                
                % Set a title for the axis
                title(app.UIAxes, 'Grayscale Image Histogram');
                hold(app.UIAxes, 'off');
           else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
           end
        end
        
        function setHistogramOutput(app, numChannel)
            % clear histogram
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            title(app.UIAxes4, '');
            title(app.UIAxes5, '');
            title(app.UIAxes6, '');
            
            % handle rgb image
            if numChannel == 3
                % Display the image in the uiimage component
                app.OutputImage.ImageSource = app.Outputimagedata;
            
                % Separate the RGB channels
                redChannel = app.Outputimagedata(:, :, 1);
                greenChannel = app.Outputimagedata(:, :, 2);
                blueChannel = app.Outputimagedata(:, :, 3);
        
                % set value histogram
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);
                
                % calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate histogram
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes4, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(app.UIAxes4, 'on');
                bar(app.UIAxes5, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(app.UIAxes5, 'on');
                bar(app.UIAxes6, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(app.UIAxes6, 'on');
    
                % Set titles for each axes
                title(app.UIAxes4, 'Red Channel Histogram');
                title(app.UIAxes5, 'Green Channel Histogram');
                title(app.UIAxes6, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes4, [0, max(redHistogram)]); 
                ylim(app.UIAxes5, [0, max(greenHistogram)]);
                ylim(app.UIAxes6, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes4, [0, 256]);
                xlim(app.UIAxes5, [0, 256]);
                xlim(app.UIAxes6, [0, 256]);

                % Turn off hold for all axes
                hold(app.UIAxes4, 'off');
                hold(app.UIAxes5, 'off');
                hold(app.UIAxes6, 'off');

            % handle grayscale image
            elseif numChannel == 1
                % convert grayscale to format rgb to show image
                rgbimage = cat(3, app.Outputimagedata,app.Outputimagedata,app.Outputimagedata);

                % Display the image in the uiimage component
                app.OutputImage.ImageSource = rgbimage;
            
                % grey channel
                greyChannel = app.Outputimagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate histogram
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes4, 0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(app.UIAxes4, 'on');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes4, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes4, [0, 256]);

                % Set a title for the axis
                title(app.UIAxes4, 'Grayscale Image Histogram');
                hold(app.UIAxes4, 'off');
            end
        end
            

        function setText(app, label)
            % set text label
            app.NoFileSelectedLabel.Text = label;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function apply(app, event)
            % Apply so that output image -> input image
            app.Inputimagedata = app.Outputimagedata;
            
            % set histogram and image output
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramInput(numChannels);
        end

        % Callback function: ChooseImageButton, OpenMenu
        function chooseFileHandler(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.jpeg;*.tif;*.bmp', 'Image Files (*.jpg,*.png,*.jpeg,*.tif,*.bmp)'}, 'Select an image file');
            
            if isequal(file, 0) || isequal(path, 0)
               % Handle the case when the user cancels the file selection
               app.NoFileSelectedLabel.Text = 'No file selected';
               return; % Exit the function
            end
            

            % Create the full file path
            fullFilePath = fullfile(path, file);
            % [ ~ , ~ , ext ] = fileparts( fullFilePath );
  
            % Display the image in the app.SpecImage component
            % Load the BMP image
            bmpImage = imread(fullFilePath);
        
            app.Fullimagename = fullFilePath;
            app.Inputimagedata = bmpImage; 
            app.Outputimagedata = bmpImage;
            % Convert BMP to PNG format
            % You may need to adjust the filename and path as needed
            pngFileName = 'converted_image.png';
            pngFilePath = fullfile(path, pngFileName);
            imwrite(bmpImage, pngFilePath);
            
            ToggleLoGInput(app, false)
            ToggleSobelInput(app, false)
            numChannels = size(bmpImage, 3);

            % set all variable
            app.setText(file)
            app.setHistogramInput(numChannels);
        end

        % Button pushed function: ProcessButton
        function ProcessDeteksiTepi(app, event)
            % num channel citra
            numChannels = size(app.Outputimagedata, 3);
            
            grayscaleImage = rgb2gray(app.Inputimagedata);
            % Compute the threshold using Otsu's method
            
            % TODO: need Threshold the image
            threshold = app.ThresholdEditField.Value;
            variansi = app.variansiLogEditField.Value;
            filter_size = app.filtersizeLogEditField.Value;
            c = app.csobelEditField.Value;
            if (app.CannyButton.Value == true)
                app.Outputimagedata = canny(grayscaleImage, threshold / 255 );
            elseif (app.SobelButton.Value == true)
                app.Outputimagedata =  sobel(grayscaleImage, c,threshold);
            elseif (app.LoGButton.Value  == true)
                app.Outputimagedata =  laplaceOfGaussian(grayscaleImage,filter_size, variansi,threshold);
            elseif (app.LaplaceButton.Value == true)
                app.Outputimagedata = laplace(grayscaleImage, threshold);
            elseif (app.PrewitButton.Value == true)
                app.Outputimagedata = prewit(grayscaleImage, threshold);
            elseif (app.RobertsButton.Value == true)
                app.Outputimagedata = roberts(grayscaleImage, threshold);
            else
                disp('ALERT: pilih yang benar');
            end
            
            radiusClose = app.RadiusCloseEditField.Value;
            radiusOpen = app.RadiusOpenEditField.Value;
            fill_tresh = app.FillThresholdEditField.Value;
            
            imshow(app.Outputimagedata, 'Parent',app.EdgeImage);
            % operasi penutupan citra
            if (strcmp(app.BentukDropDown.Value,'line'))
                result = imclose(app.Outputimagedata , strel(app.BentukDropDown.Value, radiusClose, 0));
            else
                result = imclose(app.Outputimagedata , strel(app.BentukDropDown.Value, radiusClose));
            end
            % operasi filling holes
            result = imfill(result,'holes');
            % menghilangkan noise dan detail detail kecil
            result = imopen(result,strel(ones(radiusOpen,radiusOpen)));
            % menghapus objek-objek kecil objek-objek luar kurang dari 
            result = bwareaopen(result, fill_tresh);
            % operasi perkalian untuk masing masing kernel untuk

            if (numChannels == 1)
                gray_processed=app.Inputimagedata(:,:,1).*uint8(result);
                app.Outputimagedata=cat(3,gray_processed,gray_processed,gray_processed);
            elseif (numChannels == 3)
                red_processed=app.Inputimagedata(:,:,1).*uint8(result);
                green_processed=app.Inputimagedata(:,:,2).*uint8(result);
                blue_processed=app.Inputimagedata(:,:,3).*uint8(result);
                app.Outputimagedata=cat(3,red_processed,green_processed,blue_processed);
            else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
            end
            % show image
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            selectedButton.Text
            switch (selectedButton.Text)
                case "LoG"
                    ToggleLoGInput(app, true)
                    ToggleSobelInput(app, false)
                case "Laplace"
                    ToggleLoGInput(app, false)
                    ToggleSobelInput(app, false)
                case "Sobel"
                    ToggleLoGInput(app, false)
                    ToggleSobelInput(app, true)
                case "Prewit"
                    ToggleLoGInput(app, false)
                    ToggleSobelInput(app, false)
                case "Roberts"
                    ToggleLoGInput(app, false)
                    ToggleSobelInput(app, false)
                case "Canny"
                    ToggleLoGInput(app, false)
                    ToggleSobelInput(app, false)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @chooseFileHandler, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.Tooltip = {'Histogram'};
            app.OpenFileTab = uitab(app.TabGroup);
            app.OpenFileTab.Title = 'Open File';
            app.GridLayout6_2 = uigridlayout(app.OpenFileTab);
            app.NoFileSelectedLabel = uilabel(app.GridLayout6_2);
            app.NoFileSelectedLabel.Text = 'No File Selected';
            app.ChooseImageButton = uibutton(app.GridLayout6_2, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileHandler, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.TepiTab = uitab(app.TabGroup);
            app.TepiTab.Title = 'Tepi';
            app.GridLayout6 = uigridlayout(app.TepiTab);
            app.ButtonGroup = uibuttongroup(app.GridLayout6);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.LaplaceButton = uiradiobutton(app.ButtonGroup);
            app.LaplaceButton.Text = 'Laplace';
            app.LaplaceButton.Value = true;
            app.LoGButton = uiradiobutton(app.ButtonGroup);
            app.LoGButton.Text = 'LoG';
            app.SobelButton = uiradiobutton(app.ButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.PrewitButton = uiradiobutton(app.ButtonGroup);
            app.PrewitButton.Text = 'Prewit';
            app.RobertsButton = uiradiobutton(app.ButtonGroup);
            app.RobertsButton.Text = 'Roberts';
            app.CannyButton = uiradiobutton(app.ButtonGroup);
            app.CannyButton.Text = 'Canny';
            app.ProcessButton = uibutton(app.GridLayout6, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDeteksiTepi, true);
            app.ProcessButton.Text = 'Process';
            app.RadiusCloseEditFieldLabel = uilabel(app.GridLayout6);
            app.RadiusCloseEditFieldLabel.Text = 'Radius Close';
            app.RadiusCloseEditField = uieditfield(app.GridLayout6, 'numeric');
            app.FillThresholdEditFieldLabel = uilabel(app.GridLayout6);
            app.FillThresholdEditFieldLabel.Text = 'Fill Threshold';
            app.FillThresholdEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout6);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.GridLayout6, 'numeric');
            app.filtersizeLogEditFieldLabel = uilabel(app.GridLayout6);
            app.filtersizeLogEditFieldLabel.Text = 'filter size (Log)';
            app.filtersizeLogEditField = uieditfield(app.GridLayout6, 'numeric');
            app.filtersizeLogEditField.Limits = [1 Inf];
            app.filtersizeLogEditField.Value = 5;
            app.variansiLogEditFieldLabel = uilabel(app.GridLayout6);
            app.variansiLogEditFieldLabel.Text = 'variansi (Log)';
            app.variansiLogEditField = uieditfield(app.GridLayout6, 'numeric');
            app.variansiLogEditField.Limits = [0.0001 Inf];
            app.variansiLogEditField.Value = 1;
            app.csobelEditFieldLabel = uilabel(app.GridLayout6);
            app.csobelEditFieldLabel.Text = 'c (sobel)';
            app.csobelEditField = uieditfield(app.GridLayout6, 'numeric');
            app.csobelEditField.Limits = [1 Inf];
            app.csobelEditField.Value = 2;
            app.BentukDropDownLabel = uilabel(app.GridLayout6);
            app.BentukDropDownLabel.Text = 'Bentuk';
            app.BentukDropDown = uidropdown(app.GridLayout6);
            app.BentukDropDown.Items = {'diamond', 'disk', 'octagon', 'line', 'square', 'sphere'};
            app.BentukDropDown.Value = 'sphere';
            app.RadiusOpenEditFieldLabel = uilabel(app.GridLayout6);
            app.RadiusOpenEditFieldLabel.Text = 'Radius Open';
            app.RadiusOpenEditField = uieditfield(app.GridLayout6, 'numeric');
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.ImageTab = uitab(app.TabGroup2);
            app.ImageTab.Title = 'Image';
            app.GridLayout5 = uigridlayout(app.ImageTab);
            app.EdgeImage = uiaxes(app.GridLayout5);
            title(app.EdgeImage, 'Title')
            xlabel(app.EdgeImage, 'X')
            ylabel(app.EdgeImage, 'Y')
            zlabel(app.EdgeImage, 'Z')
            app.OutputImage = uiimage(app.GridLayout5);
            app.InputImage = uiimage(app.GridLayout5);
            app.HistogramTab_2 = uitab(app.TabGroup2);
            app.HistogramTab_2.Title = 'Histogram';
            app.GridLayout4 = uigridlayout(app.HistogramTab_2);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout4);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout4);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout4);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.InputLabel = uilabel(app.GridLayout4);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.GridLayout4);
            app.OutputLabel.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adohe_fototoko

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end